classdef Radar_Musical_Instrument < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        ConfigTab                       matlab.ui.container.Tab
        InitializemmWaveStudioPanel     matlab.ui.container.Panel
        InitializeButton                matlab.ui.control.Button
        InitializeLamp                  matlab.ui.control.Lamp
        ESVersionDropDown               matlab.ui.control.DropDown
        RadarConfigurationPanel         matlab.ui.container.Panel
        StartFreqGHzEditFieldLabel      matlab.ui.control.Label
        StartFreqGHzEditField           matlab.ui.control.NumericEditField
        FreqSlopeMHzusEditFieldLabel    matlab.ui.control.Label
        FreqSlopeMHzusEditField         matlab.ui.control.NumericEditField
        IdleTimeusEditFieldLabel        matlab.ui.control.Label
        IdleTimeusEditField             matlab.ui.control.NumericEditField
        TXStartTimeusEditFieldLabel     matlab.ui.control.Label
        TXStartTimeusEditField          matlab.ui.control.NumericEditField
        ADCStartTimeusEditFieldLabel    matlab.ui.control.Label
        ADCStartTimeusEditField         matlab.ui.control.NumericEditField
        ADCSamplesEditFieldLabel        matlab.ui.control.Label
        ADCSamplesEditField             matlab.ui.control.NumericEditField
        SampleRatekspsEditFieldLabel    matlab.ui.control.Label
        SampleRatekspsEditField         matlab.ui.control.NumericEditField
        RampEndTimeusEditFieldLabel     matlab.ui.control.Label
        RampEndTimeusEditField          matlab.ui.control.NumericEditField
        NoofFramesEditFieldLabel        matlab.ui.control.Label
        NoofFramesEditField             matlab.ui.control.NumericEditField
        NoofChirpLoopsEditFieldLabel    matlab.ui.control.Label
        NoofChirpLoopsEditField         matlab.ui.control.NumericEditField
        PeriodicitymsEditFieldLabel     matlab.ui.control.Label
        PeriodicitymsEditField          matlab.ui.control.NumericEditField
        ConfigureRadarButton            matlab.ui.control.Button
        Rx0CheckBox                     matlab.ui.control.CheckBox
        Rx1CheckBox                     matlab.ui.control.CheckBox
        Rx3CheckBox                     matlab.ui.control.CheckBox
        Rx2CheckBox                     matlab.ui.control.CheckBox
        Tx0CheckBox                     matlab.ui.control.CheckBox
        Tx1CheckBox                     matlab.ui.control.CheckBox
        Tx2CheckBox                     matlab.ui.control.CheckBox
        ConfigureRadarLamp              matlab.ui.control.Lamp
        PhysicalElementsUIAxes          matlab.ui.control.UIAxes
        VirtualElementsUIAxes           matlab.ui.control.UIAxes
        Image2                          matlab.ui.control.Image
        DisconnectOptionalPanel         matlab.ui.container.Panel
        DisconnectRadarButton           matlab.ui.control.Button
        DisconnectDCAButton             matlab.ui.control.Button
        StartDataReaderPanel            matlab.ui.container.Panel
        StartDataReaderButton           matlab.ui.control.Button
        DataReaderLamp                  matlab.ui.control.Lamp
        FileNameEditFieldLabel          matlab.ui.control.Label
        FileNameEditField               matlab.ui.control.EditField
        SaveDataCheckBox                matlab.ui.control.CheckBox
        ChirpCharacteristicsPanel       matlab.ui.container.Panel
        DutyCycleEditFieldLabel         matlab.ui.control.Label
        DutyCycleEditField              matlab.ui.control.NumericEditField
        MaxRangemEditFieldLabel         matlab.ui.control.Label
        MaxRangemEditField              matlab.ui.control.NumericEditField
        BandwidthGHzEditFieldLabel      matlab.ui.control.Label
        BandwidthGHzEditField           matlab.ui.control.NumericEditField
        RangeResmEditFieldLabel         matlab.ui.control.Label
        RangeResmEditField              matlab.ui.control.NumericEditField
        SaveLoadParametersPanel         matlab.ui.container.Panel
        SaveNameEditFieldLabel          matlab.ui.control.Label
        SaveNameEditField               matlab.ui.control.EditField
        LoadNameEditFieldLabel          matlab.ui.control.Label
        LoadNameEditField               matlab.ui.control.EditField
        SaveButton                      matlab.ui.control.Button
        LoadButton                      matlab.ui.control.Button
        SetupTab                        matlab.ui.container.Tab
        FFTSizeSliderLabel              matlab.ui.control.Label
        FFTSizeSlider                   matlab.ui.control.Slider
        RangemSliderLabel               matlab.ui.control.Label
        RangemSlider                    matlab.ui.control.Slider
        ImageSizeSliderLabel            matlab.ui.control.Label
        ImageSizeSlider                 matlab.ui.control.Slider
        CrossRangemSliderLabel          matlab.ui.control.Label
        CrossRangemSlider               matlab.ui.control.Slider
        MusicTab                        matlab.ui.container.Tab
        NoteSetupPanel                  matlab.ui.container.Panel
        RecentNoteBufferSizeSpinnerLabel  matlab.ui.control.Label
        RecentNoteBufferSizeSpinner     matlab.ui.control.Spinner
        RecentModBufferSizeSpinnerLabel  matlab.ui.control.Label
        RecentModBufferSizeSpinner      matlab.ui.control.Spinner
        NumberofNotesSpinnerLabel       matlab.ui.control.Label
        NumberofNotesSpinner            matlab.ui.control.Spinner
        NoteDurationSpinnerLabel        matlab.ui.control.Label
        NoteDurationSpinner             matlab.ui.control.Spinner
        StartingOctaveSpinnerLabel      matlab.ui.control.Label
        StartingOctaveSpinner           matlab.ui.control.Spinner
        KeyDropDownLabel                matlab.ui.control.Label
        KeyDropDown                     matlab.ui.control.DropDown
        KeyMethodDropDown               matlab.ui.control.DropDown
        ModulationSetupPanel            matlab.ui.container.Panel
        ModulationCheckBox              matlab.ui.control.CheckBox
        ThresholdEditFieldLabel         matlab.ui.control.Label
        ModThresholdEditField           matlab.ui.control.NumericEditField
        QuantizationLevelsEditFieldLabel  matlab.ui.control.Label
        QuantizationLevelsEditField     matlab.ui.control.NumericEditField
        DiffusionSigmaEditFieldLabel    matlab.ui.control.Label
        ModDiffusionSigmaEditField      matlab.ui.control.NumericEditField
        ResampleSigmaEditFieldLabel     matlab.ui.control.Label
        ModResampleSigmaEditField       matlab.ui.control.NumericEditField
        EpsilonLabel                    matlab.ui.control.Label
        ModEpsilonEditField             matlab.ui.control.NumericEditField
        NoofParticlesLabel              matlab.ui.control.Label
        ModNumParticlesEditField        matlab.ui.control.NumericEditField
        ModParticleFilterCheckBox       matlab.ui.control.CheckBox
        MIDIOutputPanel                 matlab.ui.container.Panel
        MIDIDeviceNameEditFieldLabel    matlab.ui.control.Label
        MIDIDeviceNameEditField         matlab.ui.control.EditField
        MIDIOutputCheckBox              matlab.ui.control.CheckBox
        MoveKnob1Button                 matlab.ui.control.Button
        MoveKnob2Button                 matlab.ui.control.Button
        DopplerSetupPanel               matlab.ui.container.Panel
        NoofFramesEditField_2Label      matlab.ui.control.Label
        DopplerNumFramesEditField       matlab.ui.control.NumericEditField
        NoofDopplerBinsEditFieldLabel   matlab.ui.control.Label
        NumDopplerBinsEditField         matlab.ui.control.NumericEditField
        DopplerParticleFilterCheckBox   matlab.ui.control.CheckBox
        DiffusionSigmaEditFieldLabel_2  matlab.ui.control.Label
        DopplerDiffusionSigmaEditField  matlab.ui.control.NumericEditField
        ResampleSigmaEditFieldLabel_2   matlab.ui.control.Label
        DopplerResampleSigmaEditField   matlab.ui.control.NumericEditField
        EpsilonLabel_2                  matlab.ui.control.Label
        DopplerEpsilonEditField         matlab.ui.control.NumericEditField
        NoofParticlesLabel_2            matlab.ui.control.Label
        DopplerNumParticlesEditField    matlab.ui.control.NumericEditField
        NoofMTIFramesEditFieldLabel     matlab.ui.control.Label
        NumMTIFramesEditField           matlab.ui.control.NumericEditField
        DopplerCheckBox                 matlab.ui.control.CheckBox
        DLocalizationPanel              matlab.ui.container.Panel
        ImprovedTrackingCheckBox        matlab.ui.control.CheckBox
        PFCheckBox                      matlab.ui.control.CheckBox
        DPFCheckBox                     matlab.ui.control.CheckBox
        DiffusionSigmaEditFieldLabel_3  matlab.ui.control.Label
        LocDiffusionSigmaEditField      matlab.ui.control.NumericEditField
        ResampleSigmaEditFieldLabel_3   matlab.ui.control.Label
        LocResampleSigmaEditField       matlab.ui.control.NumericEditField
        xRange1EpsilonLabel             matlab.ui.control.Label
        YEpsilonEditField               matlab.ui.control.NumericEditField
        NoofParticlesLabel_3            matlab.ui.control.Label
        LocNumParticlesEditField        matlab.ui.control.NumericEditField
        Range1EpsilonLabel              matlab.ui.control.Label
        ZEpsilonEditField               matlab.ui.control.NumericEditField
        NrLabel                         matlab.ui.control.Label
        NrEditField                     matlab.ui.control.NumericEditField
        AudioOutputPanel                matlab.ui.container.Panel
        AmplitudeSliderLabel            matlab.ui.control.Label
        AmplitudeSlider                 matlab.ui.control.Slider
        AudioOutputCheckBox             matlab.ui.control.CheckBox
        WaveformButtonGroup             matlab.ui.container.ButtonGroup
        SineButton                      matlab.ui.control.RadioButton
        TriangleButton                  matlab.ui.control.RadioButton
        SquareButton                    matlab.ui.control.RadioButton
        VisualizationPanel              matlab.ui.container.Panel
        CrossRangevsTimePlotCheckBox    matlab.ui.control.CheckBox
        Position2DScatterCheckBox       matlab.ui.control.CheckBox
        OscillationPlotCheckBox         matlab.ui.control.CheckBox
        RMAPlotCheckBox                 matlab.ui.control.CheckBox
        RangevsTimePlotCheckBox         matlab.ui.control.CheckBox
        DopplerPlotCheckBox             matlab.ui.control.CheckBox
        EnhancingFCNNPanel              matlab.ui.container.Panel
        FCNNEditFieldLabel              matlab.ui.control.Label
        FCNNEditField                   matlab.ui.control.EditField
        DeepLearningSwitch              matlab.ui.control.Switch
        LimitedTestPanel                matlab.ui.container.Panel
        TestMusicOnceButton             matlab.ui.control.Button
        NumberofIterationsEditFieldLabel  matlab.ui.control.Label
        NumberofIterationsEditField     matlab.ui.control.NumericEditField
        RealTimeMusicButton             matlab.ui.control.StateButton
        mmWaveStudioLamp                matlab.ui.control.Lamp
        mmWaveStudioTextAreaLabel       matlab.ui.control.Label
        mmWaveStudioTextArea            matlab.ui.control.TextArea
        ConnecttommWaveStudioButton     matlab.ui.control.Button
        Image                           matlab.ui.control.Image
        RadarMusicalInstrumentLabel     matlab.ui.control.Label
        Label                           matlab.ui.control.Label
        StopFrameButton                 matlab.ui.control.Button
        TriggerFrameButton              matlab.ui.control.Button
    end

    properties (Access = public)
        %% Initialize Variables
        isConnectedmmWaveStudio;
        isCaptureActive;
        isInitialize;
        isRadarConfigure;
        isDataReader;
        isSaveData;
        isDeepLearning;
        
        Params;
        p;
        COMPort;
        imMethod;
        midi;
        
        %% Initialize Paths
        RSTD_DLL_Path;
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %% Add Necessary Directories
            addpath(genpath("./"))
            
            %% Initialize Values
            app.isConnectedmmWaveStudio = false;
            app.isCaptureActive = false;
            app.isInitialize = false;
            app.isDataReader = false;
            app.isSaveData = false;
            app.imMethod = "RMA";
            app.isDeepLearning = true;
            
            %% Initialize Params & p
            updateAll(app);
            
            %% Initialize Params
            app.Params.z0_mm = mean(app.p.zT);
            
            %% Initialize RSTD_DLL_Path
            if exist('C:\ti\mmwave_studio_02_01_01_00\mmWaveStudio\Clients\RtttNetClientController\RtttNetClientAPI.dll','file')
                app.RSTD_DLL_Path = 'C:\ti\mmwave_studio_02_01_01_00\mmWaveStudio\Clients\RtttNetClientController\RtttNetClientAPI.dll';
            elseif exist('C:\ti\mmwave_studio_01_00_00_00\mmWaveStudio\Clients\RtttNetClientController\RtttNetClientAPI.dll','file')
                app.RSTD_DLL_Path = 'D:\ti\mmwave_studio_01_00_00_00\mmWaveStudio\Clients\RtttNetClientController\RtttNetClientAPI.dll';
            else
                beep()
                warning("Please install mmWave Studio 2.1.1.0 or 1.0.0.0 on your C drive, or change the path in the startupFcn() function");
            end
            
            %% Initialize
            AWR1243_ArrayDimensions_GUI(app);
            
            pause(1)
            
            loadPathFull = "./saved/Params_v4.mat";
            load(loadPathFull,"savedParams");
            app.Params = savedParams;
            
            updateParamFields(app);
            updateAll(app);
            app.mmWaveStudioTextArea.Value = "Parameters loaded from: " + loadPathFull;
        end

        % Button pushed function: ConnecttommWaveStudioButton
        function ConnecttommWaveStudioButtonPushed(app, event)
            Connect_mmWaveStudio(app);
        end

        % Value changed function: Tx2CheckBox
        function Tx2CheckBoxValueChanged(app, event)
            updateAll(app);
        end

        % Value changed function: Tx1CheckBox
        function Tx1CheckBoxValueChanged(app, event)
            updateAll(app);
        end

        % Value changed function: StartFreqGHzEditField
        function StartFreqGHzEditFieldValueChanged(app, event)
            updateAll(app);
        end

        % Value changed function: FreqSlopeMHzusEditField
        function FreqSlopeMHzusEditFieldValueChanged(app, event)
            updateAll(app);
        end

        % Value changed function: IdleTimeusEditField
        function IdleTimeusEditFieldValueChanged(app, event)
            updateAll(app);
        end

        % Value changed function: TXStartTimeusEditField
        function TXStartTimeusEditFieldValueChanged(app, event)
            updateAll(app);
        end

        % Value changed function: ADCStartTimeusEditField
        function ADCStartTimeusEditFieldValueChanged(app, event)
            updateAll(app);
        end

        % Value changed function: ADCSamplesEditField
        function ADCSamplesEditFieldValueChanged(app, event)
            updateAll(app);
        end

        % Value changed function: SampleRatekspsEditField
        function SampleRatekspsEditFieldValueChanged(app, event)
            updateAll(app);
        end

        % Value changed function: RampEndTimeusEditField
        function RampEndTimeusEditFieldValueChanged(app, event)
            updateAll(app);
        end

        % Value changed function: NoofFramesEditField
        function NoofFramesEditFieldValueChanged(app, event)
            updateAll(app);
        end

        % Value changed function: NoofChirpLoopsEditField
        function NoofChirpLoopsEditFieldValueChanged(app, event)
            updateAll(app);
        end

        % Value changed function: PeriodicitymsEditField
        function PeriodicitymsEditFieldValueChanged(app, event)
            updateAll(app);
        end

        % Button pushed function: InitializeButton
        function InitializeButtonPushed(app, event)
            initializemmWaveStudio(app);
        end

        % Button pushed function: ConfigureRadarButton
        function ConfigureRadarButtonPushed(app, event)
            configureRadar(app);
        end

        % Button pushed function: TriggerFrameButton
        function TriggerFrameButtonPushed(app, event)
            triggerFrame(app);
        end

        % Value changed function: ImageSizeSlider
        function ImageSizeSliderValueChanged(app, event)
            updateAll(app);
        end

        % Value changed function: FFTSizeSlider
        function FFTSizeSliderValueChanged(app, event)
            updateAll(app);
        end

        % Value changed function: RangemSlider
        function RangemSliderValueChanged(app, event)
            updateAll(app);
        end

        % Value changed function: CrossRangemSlider
        function CrossRangemSliderValueChanged(app, event)
            updateAll(app);
        end

        % Button pushed function: StopFrameButton
        function StopFrameButtonPushed(app, event)
            stopFrame(app);
        end

        % Button pushed function: DisconnectRadarButton
        function DisconnectRadarButtonPushed(app, event)
            disconnectRadar(app);
        end

        % Button pushed function: DisconnectDCAButton
        function DisconnectDCAButtonPushed(app, event)
            disconnectDCA(app);
        end

        % Button pushed function: StartDataReaderButton
        function StartDataReaderButtonPushed(app, event)
            startDataReader(app);
        end

        % Value changed function: FileNameEditField
        function FileNameEditFieldValueChanged(app, event)
            updateAll(app);
        end

        % Value changed function: SaveDataCheckBox
        function SaveDataCheckBoxValueChanged(app, event)
            app.isSaveData = app.SaveDataCheckBox.Value;
            if app.isDataReader
                app.mmWaveStudioTextArea.Value = "Please close the open Data Reader and reopen!";
            else
                app.mmWaveStudioTextArea.Value = "Don't forget to start the Data Reader!";
            end
            app.DataReaderLamp.Color = 'red';
        end

        % Value changed function: RealTimeMusicButton
        function RealTimeMusicButtonValueChanged(app, event)
            if app.Params.nFrame ~= 0
                app.mmWaveStudioTextArea.Value = "ERROR! Must configure with 0 frames for real-time streaming!";
                return;
            elseif ~app.isDataReader
                app.mmWaveStudioTextArea.Value = "Please open/reopen the Data Reader before trying real-time music";
                return;
            end
            
            if ~app.RealTimeMusicButton.Value
                % Stop the Real-Time Loop
                stopFrame(app);
                app.mmWaveStudioTextArea.Value = "Stopping Real Time Music";
                return;
            end
            app.mmWaveStudioTextArea.Value = "Starting Real Time Music!";
            
            % 1. Create the System Objects
            radarMusicalInstrument = rmi_main('app',app);
            
            % 2. Trigger the Frame
            triggerFrame(app);
            
            % 3. Start the Real-Time Loop
            while app.RealTimeMusicButton.Value
                if ~app.RealTimeMusicButton.Value
                    % Stop the Real-Time Loop
                    break;
                end
                radarMusicalInstrument();
            end
            
            app.mmWaveStudioTextArea.Value = "Stopping Real Time Music";
            stopFrame(app);
            release(radarMusicalInstrument);
        end

        % Button pushed function: TestMusicOnceButton
        function TestMusicOnceButtonPushed(app, event)
            if app.Params.nFrame ~= 0
                app.mmWaveStudioTextArea.Value = "ERROR! Must configure with 0 frames for real-time streaming!";
                return;
            elseif ~app.isDataReader
                app.mmWaveStudioTextArea.Value = "Please open/reopen the Data Reader before trying real-time";
                return;
            end
            
            % 1. Create the System Objects
            radarMusicalInstrument = rmi_main('app',app);
            
            % 2. Trigger the Frame
            triggerFrame(app);
            
            % 3. Start Loop
            for i = 1:app.NumberofIterationsEditField.Value
                radarMusicalInstrument();
            end
            stopFrame(app);
            
            release(radarMusicalInstrument);
        end

        % Value changed function: DeepLearningSwitch
        function DeepLearningSwitchValueChanged(app, event)
            if string(app.DeepLearningSwitch.Value) == "On"
                app.isDeepLearning = true;
            else
                app.isDeepLearning = false;
            end
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            if exist(app.SaveNameEditField.Value + ".mat",'file')
                selection = uiconfirm(app.UIFigure,'Are you sure you want to overwrite?','Confirm Overwrite',...
                    'Icon','warning');
                if string(selection) == "Cancel"
                    app.mmWaveStudioTextArea.Value = "Parameters not saved!";
                    return;
                end
            end
            
            savedParams = app.Params;
            savePathFull = "./saved/" + app.SaveNameEditField.Value + ".mat";
            save(savePathFull,"savedParams");
            app.mmWaveStudioTextArea.Value = "Saved parameters to: " + savePathFull;
        end

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
            if ~exist(app.LoadNameEditField.Value + ".mat",'file')
                uiconfirm(app.UIFigure,"No file called " + app.LoadNameEditField.Value + ".mat to load",'Cannot Load',...
                    "Options",{'OK'},'Icon','warning');
                app.mmWaveStudioTextArea.Value = "Parameters not loaded!";
                return;
            end
            
            loadPathFull = "./saved/" + app.LoadNameEditField.Value + ".mat";
            load(loadPathFull,"savedParams");
            app.Params = savedParams;
            
            updateParamFields(app);
            updateAll(app);
            app.mmWaveStudioTextArea.Value = "Parameters loaded from: " + loadPathFull;
        end

        % Button pushed function: MoveKnob1Button
        function MoveKnob1ButtonPushed(app, event)
            if ~app.MIDIOutputCheckBox.Value
                app.mmWaveStudioTextArea.Value = "MIDI not initialized! Check the box first!";
                return;
            end
            
            channel = 1;
            knob = 1;
            for i = 1:16
                msg = midimsg('ControlChange',channel,knob,randi(127));
                midisend(app.midi.out,msg);
            end
        end

        % Button pushed function: MoveKnob2Button
        function MoveKnob2ButtonPushed(app, event)
            if ~app.MIDIOutputCheckBox.Value
                app.mmWaveStudioTextArea.Value = "MIDI not initialized! Check the box first!";
                return;
            end
            
            channel = 1;
            knob = 2;
            for ii = 1:16
                msg = midimsg('ControlChange',channel,knob,randi(127));
                midisend(app.midi.out,msg);
            end
        end

        % Value changed function: MIDIOutputCheckBox
        function MIDIOutputCheckBoxValueChanged(app, event)
            app.midi.out = mididevice(app.MIDIDeviceNameEditField.Value);
        end

        % Value changed function: PFCheckBox
        function PFCheckBoxValueChanged(app, event)
            if app.ImprovedTrackingCheckBox.Value == 0
                app.mmWaveStudioTextArea.Value = "Enable Improved Tracking First!";
                app.PFCheckBox.Value = 0;
            end
            
            if app.DPFCheckBox.Value && app.PFCheckBox.Value
                app.mmWaveStudioTextArea.Value = "Switching to PF";
                app.DPFCheckBox.Value = 0;
            end
        end

        % Value changed function: DPFCheckBox
        function DPFCheckBoxValueChanged(app, event)
            if app.ImprovedTrackingCheckBox.Value == 0
                app.mmWaveStudioTextArea.Value = "Enable Improved Tracking First!";
                app.DPFCheckBox.Value = 0;
            end
            
            if app.DPFCheckBox.Value && app.PFCheckBox.Value
                app.mmWaveStudioTextArea.Value = "Switching to DPF";
                app.PFCheckBox.Value = 0;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.ConfigTab = uitab(app.TabGroup);
            app.ConfigTab.Title = 'Config';
            app.InitializemmWaveStudioPanel = uipanel(app.ConfigTab);
            app.InitializemmWaveStudioPanel.Title = '1. Initialize mmWave Studio';
            app.InitializeButton = uibutton(app.InitializemmWaveStudioPanel, 'push');
            app.InitializeButton.ButtonPushedFcn = createCallbackFcn(app, @InitializeButtonPushed, true);
            app.InitializeButton.Text = 'Initialize';
            app.InitializeLamp = uilamp(app.InitializemmWaveStudioPanel);
            app.ESVersionDropDown = uidropdown(app.InitializemmWaveStudioPanel);
            app.ESVersionDropDown.Items = {'ES2', 'ES3'};
            app.ESVersionDropDown.Tooltip = {''};
            app.ESVersionDropDown.Value = 'ES2';
            app.RadarConfigurationPanel = uipanel(app.ConfigTab);
            app.RadarConfigurationPanel.Title = '2. Radar Configuration';
            app.StartFreqGHzEditFieldLabel = uilabel(app.RadarConfigurationPanel);
            app.StartFreqGHzEditFieldLabel.Text = 'Start Freq (GHz)';
            app.StartFreqGHzEditField = uieditfield(app.RadarConfigurationPanel, 'numeric');
            app.StartFreqGHzEditField.ValueDisplayFormat = '%11.3f';
            app.StartFreqGHzEditField.ValueChangedFcn = createCallbackFcn(app, @StartFreqGHzEditFieldValueChanged, true);
            app.StartFreqGHzEditField.Value = 77;
            app.FreqSlopeMHzusEditFieldLabel = uilabel(app.RadarConfigurationPanel);
            app.FreqSlopeMHzusEditFieldLabel.Text = 'Freq Slope (MHz/us)';
            app.FreqSlopeMHzusEditField = uieditfield(app.RadarConfigurationPanel, 'numeric');
            app.FreqSlopeMHzusEditField.ValueDisplayFormat = '%11.3f';
            app.FreqSlopeMHzusEditField.ValueChangedFcn = createCallbackFcn(app, @FreqSlopeMHzusEditFieldValueChanged, true);
            app.FreqSlopeMHzusEditField.Value = 100.036;
            app.IdleTimeusEditFieldLabel = uilabel(app.RadarConfigurationPanel);
            app.IdleTimeusEditFieldLabel.Text = 'Idle Time (us)';
            app.IdleTimeusEditField = uieditfield(app.RadarConfigurationPanel, 'numeric');
            app.IdleTimeusEditField.ValueDisplayFormat = '%11.3f';
            app.IdleTimeusEditField.ValueChangedFcn = createCallbackFcn(app, @IdleTimeusEditFieldValueChanged, true);
            app.IdleTimeusEditField.Value = 10;
            app.TXStartTimeusEditFieldLabel = uilabel(app.RadarConfigurationPanel);
            app.TXStartTimeusEditFieldLabel.Text = 'TX Start Time (us)';
            app.TXStartTimeusEditField = uieditfield(app.RadarConfigurationPanel, 'numeric');
            app.TXStartTimeusEditField.ValueDisplayFormat = '%11.3f';
            app.TXStartTimeusEditField.ValueChangedFcn = createCallbackFcn(app, @TXStartTimeusEditFieldValueChanged, true);
            app.ADCStartTimeusEditFieldLabel = uilabel(app.RadarConfigurationPanel);
            app.ADCStartTimeusEditFieldLabel.Text = 'ADC Start Time (us)';
            app.ADCStartTimeusEditField = uieditfield(app.RadarConfigurationPanel, 'numeric');
            app.ADCStartTimeusEditField.ValueDisplayFormat = '%11.3f';
            app.ADCStartTimeusEditField.ValueChangedFcn = createCallbackFcn(app, @ADCStartTimeusEditFieldValueChanged, true);
            app.ADCSamplesEditFieldLabel = uilabel(app.RadarConfigurationPanel);
            app.ADCSamplesEditFieldLabel.Text = 'ADC Samples';
            app.ADCSamplesEditField = uieditfield(app.RadarConfigurationPanel, 'numeric');
            app.ADCSamplesEditField.Limits = [0 Inf];
            app.ADCSamplesEditField.RoundFractionalValues = 'on';
            app.ADCSamplesEditField.ValueDisplayFormat = '%11.3g';
            app.ADCSamplesEditField.ValueChangedFcn = createCallbackFcn(app, @ADCSamplesEditFieldValueChanged, true);
            app.ADCSamplesEditField.Value = 79;
            app.SampleRatekspsEditFieldLabel = uilabel(app.RadarConfigurationPanel);
            app.SampleRatekspsEditFieldLabel.Text = 'Sample Rate (ksps)';
            app.SampleRatekspsEditField = uieditfield(app.RadarConfigurationPanel, 'numeric');
            app.SampleRatekspsEditField.Limits = [2000 37500];
            app.SampleRatekspsEditField.ValueDisplayFormat = '%11.3f';
            app.SampleRatekspsEditField.ValueChangedFcn = createCallbackFcn(app, @SampleRatekspsEditFieldValueChanged, true);
            app.SampleRatekspsEditField.Value = 2000;
            app.RampEndTimeusEditFieldLabel = uilabel(app.RadarConfigurationPanel);
            app.RampEndTimeusEditFieldLabel.Text = 'Ramp End Time (us)';
            app.RampEndTimeusEditField = uieditfield(app.RadarConfigurationPanel, 'numeric');
            app.RampEndTimeusEditField.ValueDisplayFormat = '%11.3f';
            app.RampEndTimeusEditField.ValueChangedFcn = createCallbackFcn(app, @RampEndTimeusEditFieldValueChanged, true);
            app.RampEndTimeusEditField.Value = 39.98;
            app.NoofFramesEditFieldLabel = uilabel(app.RadarConfigurationPanel);
            app.NoofFramesEditFieldLabel.Text = 'No of Frames';
            app.NoofFramesEditField = uieditfield(app.RadarConfigurationPanel, 'numeric');
            app.NoofFramesEditField.ValueDisplayFormat = '%11.3g';
            app.NoofFramesEditField.ValueChangedFcn = createCallbackFcn(app, @NoofFramesEditFieldValueChanged, true);
            app.NoofChirpLoopsEditFieldLabel = uilabel(app.RadarConfigurationPanel);
            app.NoofChirpLoopsEditFieldLabel.Enable = 'off';
            app.NoofChirpLoopsEditFieldLabel.Text = 'No of Chirp Loops';
            app.NoofChirpLoopsEditField = uieditfield(app.RadarConfigurationPanel, 'numeric');
            app.NoofChirpLoopsEditField.ValueDisplayFormat = '%11.3g';
            app.NoofChirpLoopsEditField.ValueChangedFcn = createCallbackFcn(app, @NoofChirpLoopsEditFieldValueChanged, true);
            app.NoofChirpLoopsEditField.Enable = 'off';
            app.NoofChirpLoopsEditField.Value = 1;
            app.PeriodicitymsEditFieldLabel = uilabel(app.RadarConfigurationPanel);
            app.PeriodicitymsEditFieldLabel.Text = 'Periodicity (ms)';
            app.PeriodicitymsEditField = uieditfield(app.RadarConfigurationPanel, 'numeric');
            app.PeriodicitymsEditField.ValueDisplayFormat = '%11.3f';
            app.PeriodicitymsEditField.ValueChangedFcn = createCallbackFcn(app, @PeriodicitymsEditFieldValueChanged, true);
            app.PeriodicitymsEditField.Value = 20;
            app.ConfigureRadarButton = uibutton(app.RadarConfigurationPanel, 'push');
            app.ConfigureRadarButton.ButtonPushedFcn = createCallbackFcn(app, @ConfigureRadarButtonPushed, true);
            app.ConfigureRadarButton.Text = 'Configure Radar';
            app.Rx0CheckBox = uicheckbox(app.RadarConfigurationPanel);
            app.Rx0CheckBox.Enable = 'off';
            app.Rx0CheckBox.Text = 'Rx0';
            app.Rx0CheckBox.Value = true;
            app.Rx1CheckBox = uicheckbox(app.RadarConfigurationPanel);
            app.Rx1CheckBox.Enable = 'off';
            app.Rx1CheckBox.Text = 'Rx1';
            app.Rx1CheckBox.Value = true;
            app.Rx3CheckBox = uicheckbox(app.RadarConfigurationPanel);
            app.Rx3CheckBox.Enable = 'off';
            app.Rx3CheckBox.Text = 'Rx3';
            app.Rx3CheckBox.Value = true;
            app.Rx2CheckBox = uicheckbox(app.RadarConfigurationPanel);
            app.Rx2CheckBox.Enable = 'off';
            app.Rx2CheckBox.Text = 'Rx2';
            app.Rx2CheckBox.Value = true;
            app.Tx0CheckBox = uicheckbox(app.RadarConfigurationPanel);
            app.Tx0CheckBox.Enable = 'off';
            app.Tx0CheckBox.Text = 'Tx0';
            app.Tx0CheckBox.Value = true;
            app.Tx1CheckBox = uicheckbox(app.RadarConfigurationPanel);
            app.Tx1CheckBox.ValueChangedFcn = createCallbackFcn(app, @Tx1CheckBoxValueChanged, true);
            app.Tx1CheckBox.Text = 'Tx1';
            app.Tx2CheckBox = uicheckbox(app.RadarConfigurationPanel);
            app.Tx2CheckBox.ValueChangedFcn = createCallbackFcn(app, @Tx2CheckBoxValueChanged, true);
            app.Tx2CheckBox.Text = 'Tx2';
            app.Tx2CheckBox.Value = true;
            app.ConfigureRadarLamp = uilamp(app.RadarConfigurationPanel);
            app.PhysicalElementsUIAxes = uiaxes(app.ConfigTab);
            title(app.PhysicalElementsUIAxes, 'Title')
            xlabel(app.PhysicalElementsUIAxes, 'X')
            ylabel(app.PhysicalElementsUIAxes, 'Y')
            app.VirtualElementsUIAxes = uiaxes(app.ConfigTab);
            title(app.VirtualElementsUIAxes, 'Title')
            xlabel(app.VirtualElementsUIAxes, 'X')
            ylabel(app.VirtualElementsUIAxes, 'Y')
            app.Image2 = uiimage(app.ConfigTab);
            app.Image2.ImageSource = 'tiChirpDiagram.png';
            app.DisconnectOptionalPanel = uipanel(app.ConfigTab);
            app.DisconnectOptionalPanel.Title = 'Disconnect (Optional)';
            app.DisconnectRadarButton = uibutton(app.DisconnectOptionalPanel, 'push');
            app.DisconnectRadarButton.ButtonPushedFcn = createCallbackFcn(app, @DisconnectRadarButtonPushed, true);
            app.DisconnectRadarButton.Text = 'Disconnect Radar';
            app.DisconnectDCAButton = uibutton(app.DisconnectOptionalPanel, 'push');
            app.DisconnectDCAButton.ButtonPushedFcn = createCallbackFcn(app, @DisconnectDCAButtonPushed, true);
            app.DisconnectDCAButton.Text = 'Disconnect DCA';
            app.StartDataReaderPanel = uipanel(app.ConfigTab);
            app.StartDataReaderPanel.Title = '3. Start Data Reader';
            app.StartDataReaderButton = uibutton(app.StartDataReaderPanel, 'push');
            app.StartDataReaderButton.ButtonPushedFcn = createCallbackFcn(app, @StartDataReaderButtonPushed, true);
            app.StartDataReaderButton.Text = 'Start Data Reader';
            app.DataReaderLamp = uilamp(app.StartDataReaderPanel);
            app.FileNameEditFieldLabel = uilabel(app.StartDataReaderPanel);
            app.FileNameEditFieldLabel.Text = 'File Name:';
            app.FileNameEditField = uieditfield(app.StartDataReaderPanel, 'text');
            app.FileNameEditField.ValueChangedFcn = createCallbackFcn(app, @FileNameEditFieldValueChanged, true);
            app.FileNameEditField.Value = 'filename.bin';
            app.SaveDataCheckBox = uicheckbox(app.StartDataReaderPanel);
            app.SaveDataCheckBox.ValueChangedFcn = createCallbackFcn(app, @SaveDataCheckBoxValueChanged, true);
            app.SaveDataCheckBox.Text = 'Save Data?';
            app.ChirpCharacteristicsPanel = uipanel(app.ConfigTab);
            app.ChirpCharacteristicsPanel.Title = 'Chirp Characteristics';
            app.DutyCycleEditFieldLabel = uilabel(app.ChirpCharacteristicsPanel);
            app.DutyCycleEditFieldLabel.Text = 'Duty Cycle (%)';
            app.DutyCycleEditField = uieditfield(app.ChirpCharacteristicsPanel, 'numeric');
            app.DutyCycleEditField.Editable = 'off';
            app.MaxRangemEditFieldLabel = uilabel(app.ChirpCharacteristicsPanel);
            app.MaxRangemEditFieldLabel.Text = 'Max Range (m)';
            app.MaxRangemEditField = uieditfield(app.ChirpCharacteristicsPanel, 'numeric');
            app.MaxRangemEditField.Editable = 'off';
            app.BandwidthGHzEditFieldLabel = uilabel(app.ChirpCharacteristicsPanel);
            app.BandwidthGHzEditFieldLabel.Text = 'Bandwidth (GHz)';
            app.BandwidthGHzEditField = uieditfield(app.ChirpCharacteristicsPanel, 'numeric');
            app.BandwidthGHzEditField.Editable = 'off';
            app.RangeResmEditFieldLabel = uilabel(app.ChirpCharacteristicsPanel);
            app.RangeResmEditFieldLabel.Text = 'Range Res (m)';
            app.RangeResmEditField = uieditfield(app.ChirpCharacteristicsPanel, 'numeric');
            app.RangeResmEditField.Editable = 'off';
            app.SaveLoadParametersPanel = uipanel(app.ConfigTab);
            app.SaveLoadParametersPanel.Title = 'Save/Load Parameters';
            app.SaveNameEditFieldLabel = uilabel(app.SaveLoadParametersPanel);
            app.SaveNameEditFieldLabel.Text = 'Save Name';
            app.SaveNameEditField = uieditfield(app.SaveLoadParametersPanel, 'text');
            app.SaveNameEditField.Value = 'Params_v4';
            app.LoadNameEditFieldLabel = uilabel(app.SaveLoadParametersPanel);
            app.LoadNameEditFieldLabel.Text = 'Load Name';
            app.LoadNameEditField = uieditfield(app.SaveLoadParametersPanel, 'text');
            app.LoadNameEditField.Value = 'Params_v5';
            app.SaveButton = uibutton(app.SaveLoadParametersPanel, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.LoadButton = uibutton(app.SaveLoadParametersPanel, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = 'Load';
            app.SetupTab = uitab(app.TabGroup);
            app.SetupTab.Title = 'Setup';
            app.FFTSizeSliderLabel = uilabel(app.SetupTab);
            app.FFTSizeSliderLabel.Text = 'FFT Size';
            app.FFTSizeSlider = uislider(app.SetupTab);
            app.FFTSizeSlider.Limits = [64 2048];
            app.FFTSizeSlider.MajorTicks = [64 256 512 1024 2048];
            app.FFTSizeSlider.ValueChangedFcn = createCallbackFcn(app, @FFTSizeSliderValueChanged, true);
            app.FFTSizeSlider.MinorTicks = [64 256 512 1024 2048];
            app.FFTSizeSlider.Value = 512;
            app.RangemSliderLabel = uilabel(app.SetupTab);
            app.RangemSliderLabel.Text = 'Range (m)';
            app.RangemSlider = uislider(app.SetupTab);
            app.RangemSlider.Limits = [0 1.5];
            app.RangemSlider.ValueChangedFcn = createCallbackFcn(app, @RangemSliderValueChanged, true);
            app.RangemSlider.Value = 0.5;
            app.ImageSizeSliderLabel = uilabel(app.SetupTab);
            app.ImageSizeSliderLabel.Enable = 'off';
            app.ImageSizeSliderLabel.Text = 'Image Size';
            app.ImageSizeSlider = uislider(app.SetupTab);
            app.ImageSizeSlider.Limits = [16 1024];
            app.ImageSizeSlider.ValueChangedFcn = createCallbackFcn(app, @ImageSizeSliderValueChanged, true);
            app.ImageSizeSlider.Enable = 'off';
            app.ImageSizeSlider.Value = 64;
            app.CrossRangemSliderLabel = uilabel(app.SetupTab);
            app.CrossRangemSliderLabel.Text = 'Cross Range (m)';
            app.CrossRangemSlider = uislider(app.SetupTab);
            app.CrossRangemSlider.Limits = [0.04 0.16];
            app.CrossRangemSlider.ValueChangedFcn = createCallbackFcn(app, @CrossRangemSliderValueChanged, true);
            app.CrossRangemSlider.Value = 0.1;
            app.MusicTab = uitab(app.TabGroup);
            app.MusicTab.Title = 'Music';
            app.NoteSetupPanel = uipanel(app.MusicTab);
            app.NoteSetupPanel.Title = 'Note Setup';
            app.RecentNoteBufferSizeSpinnerLabel = uilabel(app.NoteSetupPanel);
            app.RecentNoteBufferSizeSpinnerLabel.Text = 'Recent Note Buffer Size';
            app.RecentNoteBufferSizeSpinner = uispinner(app.NoteSetupPanel);
            app.RecentNoteBufferSizeSpinner.Limits = [0 32];
            app.RecentNoteBufferSizeSpinner.RoundFractionalValues = 'on';
            app.RecentNoteBufferSizeSpinner.Tooltip = {'Number of notes in memory, from which the mode is taken and used as the current note'};
            app.RecentNoteBufferSizeSpinner.Value = 8;
            app.RecentModBufferSizeSpinnerLabel = uilabel(app.NoteSetupPanel);
            app.RecentModBufferSizeSpinnerLabel.Text = 'Recent Mod Buffer Size';
            app.RecentModBufferSizeSpinner = uispinner(app.NoteSetupPanel);
            app.RecentModBufferSizeSpinner.Limits = [4 32];
            app.RecentModBufferSizeSpinner.RoundFractionalValues = 'on';
            app.RecentModBufferSizeSpinner.Tooltip = {'Number of modulation frequencies in memory'};
            app.RecentModBufferSizeSpinner.Value = 16;
            app.NumberofNotesSpinnerLabel = uilabel(app.NoteSetupPanel);
            app.NumberofNotesSpinnerLabel.Text = 'Number of Notes';
            app.NumberofNotesSpinner = uispinner(app.NoteSetupPanel);
            app.NumberofNotesSpinner.Limits = [2 80];
            app.NumberofNotesSpinner.RoundFractionalValues = 'on';
            app.NumberofNotesSpinner.Tooltip = {'Number of possible notes'};
            app.NumberofNotesSpinner.Value = 14;
            app.NoteDurationSpinnerLabel = uilabel(app.NoteSetupPanel);
            app.NoteDurationSpinnerLabel.Text = 'Note Duration';
            app.NoteDurationSpinner = uispinner(app.NoteSetupPanel);
            app.NoteDurationSpinner.Limits = [1 10];
            app.NoteDurationSpinner.RoundFractionalValues = 'on';
            app.NoteDurationSpinner.Tooltip = {'Number of microtones for and including each note'};
            app.NoteDurationSpinner.Value = 3;
            app.StartingOctaveSpinnerLabel = uilabel(app.NoteSetupPanel);
            app.StartingOctaveSpinnerLabel.Text = 'Starting Octave';
            app.StartingOctaveSpinner = uispinner(app.NoteSetupPanel);
            app.StartingOctaveSpinner.Limits = [1 8];
            app.StartingOctaveSpinner.RoundFractionalValues = 'on';
            app.StartingOctaveSpinner.Tooltip = {'Number of microtones for and including each note'};
            app.StartingOctaveSpinner.Value = 4;
            app.KeyDropDownLabel = uilabel(app.NoteSetupPanel);
            app.KeyDropDownLabel.Text = 'Key';
            app.KeyDropDown = uidropdown(app.NoteSetupPanel);
            app.KeyDropDown.Items = {'A', 'B', 'C', 'D', 'E', 'F', 'G', 'Ab', 'Bb', 'Db', 'Eb', 'Gb'};
            app.KeyDropDown.Tooltip = {''};
            app.KeyDropDown.Value = 'F';
            app.KeyMethodDropDown = uidropdown(app.NoteSetupPanel);
            app.KeyMethodDropDown.Items = {'Major', 'Pentatonic', 'Chromatic'};
            app.KeyMethodDropDown.Tooltip = {''};
            app.KeyMethodDropDown.Value = 'Pentatonic';
            app.ModulationSetupPanel = uipanel(app.MusicTab);
            app.ModulationSetupPanel.Title = 'Modulation Setup';
            app.ModulationCheckBox = uicheckbox(app.ModulationSetupPanel);
            app.ModulationCheckBox.Tooltip = {'Include modulation effect'};
            app.ModulationCheckBox.Text = 'Modulation';
            app.ModulationCheckBox.Value = true;
            app.ThresholdEditFieldLabel = uilabel(app.ModulationSetupPanel);
            app.ThresholdEditFieldLabel.Text = 'Threshold';
            app.ModThresholdEditField = uieditfield(app.ModulationSetupPanel, 'numeric');
            app.ModThresholdEditField.Value = 128;
            app.QuantizationLevelsEditFieldLabel = uilabel(app.ModulationSetupPanel);
            app.QuantizationLevelsEditFieldLabel.Text = 'Quantization Levels';
            app.QuantizationLevelsEditField = uieditfield(app.ModulationSetupPanel, 'numeric');
            app.QuantizationLevelsEditField.Limits = [0 256];
            app.QuantizationLevelsEditField.RoundFractionalValues = 'on';
            app.QuantizationLevelsEditField.Value = 4;
            app.DiffusionSigmaEditFieldLabel = uilabel(app.ModulationSetupPanel);
            app.DiffusionSigmaEditFieldLabel.Text = 'Diffusion Sigma';
            app.ModDiffusionSigmaEditField = uieditfield(app.ModulationSetupPanel, 'numeric');
            app.ModDiffusionSigmaEditField.Value = 0.0001;
            app.ResampleSigmaEditFieldLabel = uilabel(app.ModulationSetupPanel);
            app.ResampleSigmaEditFieldLabel.Text = 'Resample Sigma';
            app.ModResampleSigmaEditField = uieditfield(app.ModulationSetupPanel, 'numeric');
            app.ModResampleSigmaEditField.Value = 5;
            app.EpsilonLabel = uilabel(app.ModulationSetupPanel);
            app.EpsilonLabel.Text = '1/Epsilon';
            app.ModEpsilonEditField = uieditfield(app.ModulationSetupPanel, 'numeric');
            app.ModEpsilonEditField.Value = 16;
            app.NoofParticlesLabel = uilabel(app.ModulationSetupPanel);
            app.NoofParticlesLabel.Text = 'No. of Particles';
            app.ModNumParticlesEditField = uieditfield(app.ModulationSetupPanel, 'numeric');
            app.ModNumParticlesEditField.Value = 10;
            app.ModParticleFilterCheckBox = uicheckbox(app.ModulationSetupPanel);
            app.ModParticleFilterCheckBox.Text = 'Mod Particle Filter';
            app.ModParticleFilterCheckBox.Value = true;
            app.MIDIOutputPanel = uipanel(app.MusicTab);
            app.MIDIOutputPanel.Title = 'MIDI Output';
            app.MIDIDeviceNameEditFieldLabel = uilabel(app.MIDIOutputPanel);
            app.MIDIDeviceNameEditFieldLabel.Text = 'MIDI Device Name';
            app.MIDIDeviceNameEditField = uieditfield(app.MIDIOutputPanel, 'text');
            app.MIDIDeviceNameEditField.Value = '3- Fast Track Pro MIDI Out';
            app.MIDIOutputCheckBox = uicheckbox(app.MIDIOutputPanel);
            app.MIDIOutputCheckBox.ValueChangedFcn = createCallbackFcn(app, @MIDIOutputCheckBoxValueChanged, true);
            app.MIDIOutputCheckBox.Text = 'MIDI Output';
            app.MoveKnob1Button = uibutton(app.MIDIOutputPanel, 'push');
            app.MoveKnob1Button.ButtonPushedFcn = createCallbackFcn(app, @MoveKnob1ButtonPushed, true);
            app.MoveKnob1Button.Text = 'Move Knob 1';
            app.MoveKnob2Button = uibutton(app.MIDIOutputPanel, 'push');
            app.MoveKnob2Button.ButtonPushedFcn = createCallbackFcn(app, @MoveKnob2ButtonPushed, true);
            app.MoveKnob2Button.Text = 'Move Knob 2';
            app.DopplerSetupPanel = uipanel(app.MusicTab);
            app.DopplerSetupPanel.Title = 'Doppler Setup';
            app.NoofFramesEditField_2Label = uilabel(app.DopplerSetupPanel);
            app.NoofFramesEditField_2Label.Text = 'No. of Frames';
            app.DopplerNumFramesEditField = uieditfield(app.DopplerSetupPanel, 'numeric');
            app.DopplerNumFramesEditField.Value = 16;
            app.NoofDopplerBinsEditFieldLabel = uilabel(app.DopplerSetupPanel);
            app.NoofDopplerBinsEditFieldLabel.Text = 'No. of Doppler Bins';
            app.NumDopplerBinsEditField = uieditfield(app.DopplerSetupPanel, 'numeric');
            app.NumDopplerBinsEditField.Value = 32;
            app.DopplerParticleFilterCheckBox = uicheckbox(app.DopplerSetupPanel);
            app.DopplerParticleFilterCheckBox.Text = 'Doppler Particle Filter';
            app.DopplerParticleFilterCheckBox.Value = true;
            app.DiffusionSigmaEditFieldLabel_2 = uilabel(app.DopplerSetupPanel);
            app.DiffusionSigmaEditFieldLabel_2.Text = 'Diffusion Sigma';
            app.DopplerDiffusionSigmaEditField = uieditfield(app.DopplerSetupPanel, 'numeric');
            app.DopplerDiffusionSigmaEditField.Value = 0.0001;
            app.ResampleSigmaEditFieldLabel_2 = uilabel(app.DopplerSetupPanel);
            app.ResampleSigmaEditFieldLabel_2.Text = 'Resample Sigma';
            app.DopplerResampleSigmaEditField = uieditfield(app.DopplerSetupPanel, 'numeric');
            app.DopplerResampleSigmaEditField.Value = 5;
            app.EpsilonLabel_2 = uilabel(app.DopplerSetupPanel);
            app.EpsilonLabel_2.Text = '1/Epsilon';
            app.DopplerEpsilonEditField = uieditfield(app.DopplerSetupPanel, 'numeric');
            app.DopplerEpsilonEditField.Value = 16;
            app.NoofParticlesLabel_2 = uilabel(app.DopplerSetupPanel);
            app.NoofParticlesLabel_2.Text = 'No. of Particles';
            app.DopplerNumParticlesEditField = uieditfield(app.DopplerSetupPanel, 'numeric');
            app.DopplerNumParticlesEditField.Value = 10;
            app.NoofMTIFramesEditFieldLabel = uilabel(app.DopplerSetupPanel);
            app.NoofMTIFramesEditFieldLabel.Text = 'No. of MTI Frames';
            app.NumMTIFramesEditField = uieditfield(app.DopplerSetupPanel, 'numeric');
            app.NumMTIFramesEditField.Value = 4;
            app.DopplerCheckBox = uicheckbox(app.DopplerSetupPanel);
            app.DopplerCheckBox.Tooltip = {'Include modulation effect'};
            app.DopplerCheckBox.Text = 'Doppler';
            app.DopplerCheckBox.Value = true;
            app.DLocalizationPanel = uipanel(app.MusicTab);
            app.DLocalizationPanel.Title = '2-D Localization';
            app.ImprovedTrackingCheckBox = uicheckbox(app.DLocalizationPanel);
            app.ImprovedTrackingCheckBox.Text = 'Improved Tracking';
            app.ImprovedTrackingCheckBox.Value = true;
            app.PFCheckBox = uicheckbox(app.DLocalizationPanel);
            app.PFCheckBox.ValueChangedFcn = createCallbackFcn(app, @PFCheckBoxValueChanged, true);
            app.PFCheckBox.Text = 'PF';
            app.PFCheckBox.Value = true;
            app.DPFCheckBox = uicheckbox(app.DLocalizationPanel);
            app.DPFCheckBox.ValueChangedFcn = createCallbackFcn(app, @DPFCheckBoxValueChanged, true);
            app.DPFCheckBox.Text = 'DPF';
            app.DiffusionSigmaEditFieldLabel_3 = uilabel(app.DLocalizationPanel);
            app.DiffusionSigmaEditFieldLabel_3.Text = 'Diffusion Sigma';
            app.LocDiffusionSigmaEditField = uieditfield(app.DLocalizationPanel, 'numeric');
            app.LocDiffusionSigmaEditField.Value = 0.0001;
            app.ResampleSigmaEditFieldLabel_3 = uilabel(app.DLocalizationPanel);
            app.ResampleSigmaEditFieldLabel_3.Text = 'Resample Sigma';
            app.LocResampleSigmaEditField = uieditfield(app.DLocalizationPanel, 'numeric');
            app.LocResampleSigmaEditField.Value = 5;
            app.xRange1EpsilonLabel = uilabel(app.DLocalizationPanel);
            app.xRange1EpsilonLabel.Text = 'xRange 1/Epsilon';
            app.YEpsilonEditField = uieditfield(app.DLocalizationPanel, 'numeric');
            app.YEpsilonEditField.Value = 2;
            app.NoofParticlesLabel_3 = uilabel(app.DLocalizationPanel);
            app.NoofParticlesLabel_3.Text = 'No. of Particles';
            app.LocNumParticlesEditField = uieditfield(app.DLocalizationPanel, 'numeric');
            app.LocNumParticlesEditField.Value = 10;
            app.Range1EpsilonLabel = uilabel(app.DLocalizationPanel);
            app.Range1EpsilonLabel.Text = 'Range 1/Epsilon';
            app.ZEpsilonEditField = uieditfield(app.DLocalizationPanel, 'numeric');
            app.ZEpsilonEditField.Value = 2;
            app.NrLabel = uilabel(app.DLocalizationPanel);
            app.NrLabel.Text = 'Nr';
            app.NrEditField = uieditfield(app.DLocalizationPanel, 'numeric');
            app.NrEditField.Value = 16;
            app.AudioOutputPanel = uipanel(app.MusicTab);
            app.AudioOutputPanel.Title = 'Audio Output';
            app.AmplitudeSliderLabel = uilabel(app.AudioOutputPanel);
            app.AmplitudeSliderLabel.Text = 'Amplitude';
            app.AmplitudeSlider = uislider(app.AudioOutputPanel);
            app.AmplitudeSlider.Limits = [0 1];
            app.AmplitudeSlider.Value = 0.7;
            app.AudioOutputCheckBox = uicheckbox(app.AudioOutputPanel);
            app.AudioOutputCheckBox.Tooltip = {'Output the audio'};
            app.AudioOutputCheckBox.Text = 'Audio Output';
            app.WaveformButtonGroup = uibuttongroup(app.AudioOutputPanel);
            app.WaveformButtonGroup.Title = 'Waveform';
            app.SineButton = uiradiobutton(app.WaveformButtonGroup);
            app.SineButton.Text = 'Sine';
            app.SineButton.Value = true;
            app.TriangleButton = uiradiobutton(app.WaveformButtonGroup);
            app.TriangleButton.Text = 'Triangle';
            app.SquareButton = uiradiobutton(app.WaveformButtonGroup);
            app.SquareButton.Text = 'Square';
            app.VisualizationPanel = uipanel(app.MusicTab);
            app.VisualizationPanel.Title = 'Visualization';
            app.CrossRangevsTimePlotCheckBox = uicheckbox(app.VisualizationPanel);
            app.CrossRangevsTimePlotCheckBox.Tooltip = {'Plot the cross range as a function of time'};
            app.CrossRangevsTimePlotCheckBox.Text = 'Cross Range vs Time Plot';
            app.Position2DScatterCheckBox = uicheckbox(app.VisualizationPanel);
            app.Position2DScatterCheckBox.Tooltip = {'Scatter plot the estimated position of the target in the y-z plane'};
            app.Position2DScatterCheckBox.Text = 'Position 2-D Scatter';
            app.OscillationPlotCheckBox = uicheckbox(app.VisualizationPanel);
            app.OscillationPlotCheckBox.Tooltip = {'Plot the cross range oscillation frequency domain'};
            app.OscillationPlotCheckBox.Text = 'Oscillation Plot';
            app.RMAPlotCheckBox = uicheckbox(app.VisualizationPanel);
            app.RMAPlotCheckBox.Tooltip = {'TODO: Plot the range response'};
            app.RMAPlotCheckBox.Text = 'RMA Plot';
            app.RangevsTimePlotCheckBox = uicheckbox(app.VisualizationPanel);
            app.RangevsTimePlotCheckBox.Tooltip = {'TODO: Plot the range peak as a function of time'};
            app.RangevsTimePlotCheckBox.Text = 'Range vs Time Plot';
            app.DopplerPlotCheckBox = uicheckbox(app.VisualizationPanel);
            app.DopplerPlotCheckBox.Tooltip = {'Plot the cross range oscillation frequency domain'};
            app.DopplerPlotCheckBox.Text = 'Doppler Plot';
            app.EnhancingFCNNPanel = uipanel(app.MusicTab);
            app.EnhancingFCNNPanel.Title = 'Enhancing FCNN';
            app.FCNNEditFieldLabel = uilabel(app.EnhancingFCNNPanel);
            app.FCNNEditFieldLabel.Text = 'FCNN';
            app.FCNNEditField = uieditfield(app.EnhancingFCNNPanel, 'text');
            app.FCNNEditField.Value = 'rmaEnhance64x64_2';
            app.DeepLearningSwitch = uiswitch(app.EnhancingFCNNPanel, 'slider');
            app.DeepLearningSwitch.ValueChangedFcn = createCallbackFcn(app, @DeepLearningSwitchValueChanged, true);
            app.DeepLearningSwitch.Value = 'On';
            app.LimitedTestPanel = uipanel(app.MusicTab);
            app.LimitedTestPanel.Title = 'Limited Test';
            app.TestMusicOnceButton = uibutton(app.LimitedTestPanel, 'push');
            app.TestMusicOnceButton.ButtonPushedFcn = createCallbackFcn(app, @TestMusicOnceButtonPushed, true);
            app.TestMusicOnceButton.Text = 'Test Music Once';
            app.NumberofIterationsEditFieldLabel = uilabel(app.LimitedTestPanel);
            app.NumberofIterationsEditFieldLabel.Text = 'Number of Iterations';
            app.NumberofIterationsEditField = uieditfield(app.LimitedTestPanel, 'numeric');
            app.NumberofIterationsEditField.Limits = [0 Inf];
            app.NumberofIterationsEditField.RoundFractionalValues = 'on';
            app.NumberofIterationsEditField.Tooltip = {'Number of cycles for the "Test Music Once" option'};
            app.NumberofIterationsEditField.Value = 500;
            app.RealTimeMusicButton = uibutton(app.MusicTab, 'state');
            app.RealTimeMusicButton.ValueChangedFcn = createCallbackFcn(app, @RealTimeMusicButtonValueChanged, true);
            app.RealTimeMusicButton.Text = 'Real-Time Music';
            app.mmWaveStudioLamp = uilamp(app.UIFigure);
            app.mmWaveStudioTextAreaLabel = uilabel(app.UIFigure);
            app.mmWaveStudioTextAreaLabel.Text = 'mmWave Studio';
            app.mmWaveStudioTextArea = uitextarea(app.UIFigure);
            app.ConnecttommWaveStudioButton = uibutton(app.UIFigure, 'push');
            app.ConnecttommWaveStudioButton.ButtonPushedFcn = createCallbackFcn(app, @ConnecttommWaveStudioButtonPushed, true);
            app.ConnecttommWaveStudioButton.Text = 'Connect to mmWave Studio';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'imecLogo.png';
            app.RadarMusicalInstrumentLabel = uilabel(app.UIFigure);
            app.RadarMusicalInstrumentLabel.Text = 'Radar Musical Instrument';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = 'Summer 2020 | Josiah W. Smith';
            app.StopFrameButton = uibutton(app.UIFigure, 'push');
            app.StopFrameButton.ButtonPushedFcn = createCallbackFcn(app, @StopFrameButtonPushed, true);
            app.StopFrameButton.Text = 'Stop Frame';
            app.TriggerFrameButton = uibutton(app.UIFigure, 'push');
            app.TriggerFrameButton.ButtonPushedFcn = createCallbackFcn(app, @TriggerFrameButtonPushed, true);
            app.TriggerFrameButton.Text = 'Trigger Frame';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Radar_Musical_Instrument

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end